classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulaodeClculosPanel           matlab.ui.container.Panel
        DesenharPanel                   matlab.ui.container.Panel
        SimularDigitosButton            matlab.ui.control.Button
        SimularOperadorButton           matlab.ui.control.Button
        desenhoPesqRedeBtn              matlab.ui.control.Button
        desenhoRedePathEF               matlab.ui.control.EditField
        RedeLabel                       matlab.ui.control.Label
        opDesenhoRadioGroup             matlab.ui.container.ButtonGroup
        opPathEF                        matlab.ui.control.EditField
        num2PathEF                      matlab.ui.control.EditField
        num1PathEF                      matlab.ui.control.EditField
        opRadio                         matlab.ui.control.RadioButton
        num2Radio                       matlab.ui.control.RadioButton
        num1Radio                       matlab.ui.control.RadioButton
        simularDesenhoBtn               matlab.ui.control.Button
        panelResultadoDesenho           matlab.ui.container.Panel
        calculoDesenhoLbl               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        opBtn                           matlab.ui.control.Button
        InserirImagensManualmentePanel  matlab.ui.container.Panel
        num2Btn                         matlab.ui.control.Button
        panelResultadoCalculo           matlab.ui.container.Panel
        calculoLbl                      matlab.ui.control.Label
        redeEF                          matlab.ui.control.EditField
        CaminhoparaaredeEditFieldLabel  matlab.ui.control.Label
        limparBtn                       matlab.ui.control.Button
        CalcularButton                  matlab.ui.control.Button
        redeBtn                         matlab.ui.control.Button
        pathLabel_2                     matlab.ui.control.Label
        opEF                            matlab.ui.control.EditField
        OperadorEditFieldLabel          matlab.ui.control.Label
        num2EF                          matlab.ui.control.EditField
        Numero2Label                    matlab.ui.control.Label
        pathLabel                       matlab.ui.control.Label
        num1EF                          matlab.ui.control.EditField
        Numero1EditFieldLabel           matlab.ui.control.Label
        num1Btn                         matlab.ui.control.Button
        CriaodaRedeNeuronalPanel        matlab.ui.container.Panel
        ResultadoPanel                  matlab.ui.container.Panel
        lblResultadoTreino              matlab.ui.control.Label
        CriareTreinarRedeButton         matlab.ui.control.Button
        InputsPanel                     matlab.ui.container.Panel
        nomeRedeEF                      matlab.ui.control.EditField
        NomedaRedeEditFieldLabel        matlab.ui.control.Label
        pastaDD                         matlab.ui.control.DropDown
        PastaparaTreinoDropDownLabel    matlab.ui.control.Label
        ConfiguraodaRedePanel           matlab.ui.container.Panel
        camadasEscondidasSpinner        matlab.ui.control.Spinner
        CamadasEscondidasSpinnerLabel   matlab.ui.control.Label
        camada3Spinner                  matlab.ui.control.Spinner
        Camada3SpinnerLabel             matlab.ui.control.Label
        funcAtivacao3DD                 matlab.ui.control.DropDown
        FunodeAtivao3Label              matlab.ui.control.Label
        camada2Spinner                  matlab.ui.control.Spinner
        Camada2SpinnerLabel             matlab.ui.control.Label
        camada1Spinner                  matlab.ui.control.Spinner
        Camada1SpinnerLabel             matlab.ui.control.Label
        NmerodeneurniosLabel            matlab.ui.control.Label
        testEF                          matlab.ui.control.NumericEditField
        testEditFieldLabel              matlab.ui.control.Label
        valEF                           matlab.ui.control.NumericEditField
        valEditFieldLabel               matlab.ui.control.Label
        trainEF                         matlab.ui.control.NumericEditField
        trainEditFieldLabel             matlab.ui.control.Label
        funcDivisaoDD                   matlab.ui.control.DropDown
        FunodeDivisoDropDownLabel       matlab.ui.control.Label
        funcAtivacao2DD                 matlab.ui.control.DropDown
        FunodeAtivao2DropDownLabel      matlab.ui.control.Label
        funcAtivacao1DD                 matlab.ui.control.DropDown
        FunodeAtivao1DropDownLabel      matlab.ui.control.Label
        funcTreinoDD                    matlab.ui.control.DropDown
        FunodeTreinoDropDownLabel       matlab.ui.control.Label
    end


    properties (Access = private)
        % Resolucao da imagem
        IMG_RES = [150 150];
        NUM1=0;
        NUM2=0;
        OP=0;
        redeOperadores=0;
        redeDigitos=0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: num1Btn
        function num1BtnButtonPushed(app, event)
            % Callback para selecionar a imagem do numero 1
            [file,path] = uigetfile(fullfile('..','NN_datasets','*.png'));
            if isequal(file,0)
                disp('[Numero 1] O utilizador selecionou Cancelar');
            else
                disp(['[Numero 1] O utilizador selecionou "', fullfile(path,file), '"']);
                app.num1EF.Value = fullfile(path,file);
                app.num1EF.BackgroundColor = 'white';
            end
        end

        % Button pushed function: num2Btn
        function num2BtnButtonPushed(app, event)
            % Callback para selecionar a imagem do numero 2
            [file,path] = uigetfile(fullfile('..','NN_datasets','*.png'));
            if isequal(file,0)
                disp('[Numero 2] O utilizador selecionou "Cancelar"');
            else
                disp(['[Numero 2] O utilizador selecionou "', fullfile(path,file), '"']);
                app.num2EF.Value = fullfile(path,file);
                app.num2EF.BackgroundColor = 'white';
            end
        end

        % Button pushed function: opBtn
        function opBtnButtonPushed(app, event)
            % Callback para selecionar a imagem do operador
            [file,path] = uigetfile(fullfile('..','NN_datasets','*.png'));
            if isequal(file,0)
                disp('[Operador] O utilizador selecionou Cancelar');
            else
                disp(['[Operador] O utilizador selecionou "', fullfile(path,file), '"']);
                app.opEF.Value = fullfile(path,file);
                app.opEF.BackgroundColor = 'white';
            end
        end

        % Button pushed function: redeBtn
        function redeBtnButtonPushed(app, event)
            % Callback para selecionar a rede previamente treinada
            [file,path] = uigetfile(fullfile('..','networks','*.mat'));
            if isequal(file,0)
                disp('[Rede] O utilizador selecionou Cancelar');
            else
                disp(['[Rede] O utilizador selecionou "', fullfile(path,file), '"']);
                app.redeEF.Value = fullfile(path,file);
                app.redeEF.BackgroundColor = 'white';
            end
        end

        % Callback function
        function btnRedeDatasetButtonPushed(app, event)
            % Callback para selecionar a rede previamente treinada
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                disp('[Rede] O utilizador selecionou Cancelar');
            else
                disp(['[Rede] O utilizador selecionou "', fullfile(path,file), '"']);
                app.nomeRedeDatasetEF.Value = fullfile(path,file);
            end
        end

        % Callback function
        function DesenharMenuSelected(app, event)
            % Opcao para desenhar uma expressao

        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %% Calcular a expressão baseado nas imagens escolhidas
            % Verificacoes para a selecao das imagens e rede
            if isempty(app.num1EF.Value)
                disp('[ERRO] Deve selecionar a imagem do numero 1');
                app.num1EF.BackgroundColor = 'red';
            elseif isempty(app.num2EF.Value)
                disp('[ERRO] Deve selecionar a imagem do numero 2');
                app.num2EF.BackgroundColor = 'red';
            elseif isempty(app.opEF.Value)
                disp('[ERRO] Deve selecionar a imagem do operador');
                app.opEF.BackgroundColor = 'red';
            elseif isempty(app.redeEF.Value)
                disp('[ERRO] Deve selecionar a rede previamente treinada');
                app.redeEF.BackgroundColor = 'red';
            else
                % Obter os digitos extraidos da imagem
                num1 = trainFunction_d(app.num1EF.Value,app.redeEF.Value);
                num2 = trainFunction_d(app.num2EF.Value,app.redeEF.Value);
                op = trainFunction_d(app.opEF.Value,app.redeEF.Value);

                str = strcat(num1,op,num2,'=');
                switch(op)
                    case '+'
                        app.calculoLbl.Text = strcat(str,num2str(str2num(num1) + str2num(num2)));
                    case '-'
                        app.calculoLbl.Text = strcat(str,num2str(str2num(num1) - str2num(num2)));
                    case '*'
                        app.calculoLbl.Text = strcat(str,num2str(str2num(num1) * str2num(num2)));
                    case '/'
                        app.calculoLbl.Text = strcat(str,num2str(str2num(num1) / str2num(num2)));
                end
            end
        end

        % Value changed function: camadasEscondidasSpinner
        function camadasEscondidasSpinnerValueChanged(app, event)
            value = app.camadasEscondidasSpinner.Value;
            switch(value)
                case 1
                    app.camada2Spinner.Enable = "off";
                    app.camada3Spinner.Enable = "off";
                    app.funcAtivacao3DD.Enable = "off";
                case 2
                    app.camada2Spinner.Enable = "on";
                    app.camada3Spinner.Enable = "off";
                    app.funcAtivacao3DD.Enable = "on";
                case 3
                    app.camada2Spinner.Enable = "on";
                    app.camada3Spinner.Enable = "on";
                    app.funcAtivacao3DD.Enable = "on";
            end
        end

        % Button pushed function: CriareTreinarRedeButton
        function CriareTreinarRedeButtonPushed(app, event)
            % Validacao de Campos
            if isempty(app.nomeRedeEF.Value)
                disp('Deve introduzir o nome da rede');
                app.nomeRedeEF.BackgroundColor = 'red';
            elseif isempty(app.trainEF.Value)
                disp('Deve introduzir o valor de treino');
                app.trainEF.BackgroundColor = 'red';
            elseif isempty(app.valEF.Value)
                disp('Deve introduzir o valor');
                app.valEF.BackgroundColor = 'red';
            elseif isempty(app.testEF.Value)
                disp('Deve introduzir valor de test');
                app.testEF.BackgroundColor = 'red';
            else
                % Repor a cor dos EditFields
                app.nomeRedeEF.BackgroundColor = 'white';
                app.trainEF.BackgroundColor = 'white';
                app.testEF.BackgroundColor = 'white';
                app.valEF.BackgroundColor = 'white';

                camadasEscondidas = app.camadasEscondidasSpinner.Value;
                neuroniosCamada1 = 0;
                neuroniosCamada2 = 0;
                neuroniosCamada3 = 0;
                funcTreino = app.funcTreinoDD.Value;
                funcAtivacao1 = app.funcAtivacao1DD.Value;
                funcAtivacao2 = app.funcAtivacao2DD.Value;
                funcAtivacao3 = 0;
                funcDivisao = app.funcDivisaoDD.Value;
                train = app.trainEF.Value;
                val = app.valEF.Value;
                test = app.testEF.Value;
                pasta = app.pastaDD.Value;
                nomeRede = app.nomeRedeEF.Value;

                switch (camadasEscondidas)
                    case 1
                        neuroniosCamada1 = app.camada1Spinner.Value;
                    case 2
                        neuroniosCamada1 = app.camada1Spinner.Value;
                        neuroniosCamada2 = app.camada2Spinner.Value;
                    case 3
                        neuroniosCamada1 = app.camada1Spinner.Value;
                        neuroniosCamada2 = app.camada2Spinner.Value;
                        neuroniosCamada3 = app.camada3Spinner.Value;
                        funcAtivacao3 = app.funcAtivacao3DD.Value;
                end

                % Proceder a criacao da rede e teste da mesma
                [perfGlobal,perfTest] = genericTrainFunction( ...
                    camadasEscondidas,...
                    neuroniosCamada1, ...
                    neuroniosCamada2, ...
                    neuroniosCamada3, ...
                    funcTreino, ...
                    funcAtivacao1, ...
                    funcAtivacao2, ...
                    funcAtivacao3, ...
                    funcDivisao, ...
                    train, ...
                    val, ...
                    test, ...
                    pasta, ...
                    nomeRede);

                % Mostrar o resultado
                app.lblResultadoTreino.Text = sprintf('Performance Global: %.2f\nPerformance Teste: %.2f',perfGlobal,perfTest);
            end
        end

        % Button pushed function: limparBtn
        function limparBtnButtonPushed(app, event)
            app.num1EF.BackgroundColor = 'white';
            app.num1EF.Value = '';
            app.num2EF.BackgroundColor = 'white';
            app.num2EF.Value = '';
            app.opEF.BackgroundColor = 'white';
            app.opEF.Value = '';
            app.redeEF.BackgroundColor = 'white';
            app.redeEF.Value = '';
            app.calculoLbl.Text = '';
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            grayImageTmp = ones(224,224);

            h = drawfreehand(app.UIAxes);
            %h.FaceAlpha = 1;
            %h.FaceSelectable = false;
            %h.LineWidth = 5;

            % Get the xy coordinates of where they drew.
            xy = h.Position;

            % get rid of imfreehand remnant.
            delete(h);
            hold(app.UIAxes, 'on');
            xCoordinates = xy(:, 1);
            yCoordinates = xy(:, 2);
            plot(app.UIAxes, xCoordinates, yCoordinates, 'k-', 'LineWidth', 28);

            promptMessage = sprintf('Guardar imagem?');
            titleBarCaption = 'Continuar?';
            button = questdlg(promptMessage, titleBarCaption, 'Ok', 'Cancelar','Ok');
            if strcmpi(button, 'Cancelar')
                cla(app.UIAxes);
                hold(app.UIAxes, 'off');
                title(app.UIAxes, "Clique Esquerdo e Arrastar para desenhar");
                return;
            end

            title(app.UIAxes, "");

            for k = 1 : length(xCoordinates)
                row = int32(yCoordinates(k));
                column = int32(xCoordinates(k));
                disp(column);
                disp(row);
                grayImageTmp(row, column) = 0;
            end

            switch(app.opDesenhoRadioGroup.SelectedObject.Text)
                case 'Numero 1'
                    filePath = fullfile('..','images','num1.png');
                    app.num1PathEF.Value = filePath;
                    app.num1PathEF.BackgroundColor = 'white';

                    % Guardar o desenho do grafico numa imagem .png
                    exportgraphics(app.UIAxes,filePath,'Resolution',300);

                    % Adicionar um padding em volta da imagem
                    originalImage = imread(filePath);
                    paddedImage = padarray(originalImage,[110 320],255);

                    % Redimensionar a imagem para 150x150
                    paddedImage = imresize(paddedImage, app.IMG_RES);

                    % Reescrever a imagem
                    imwrite(rgb2gray(paddedImage),filePath);
                case 'Numero 2'
                    filePath = fullfile('..','images','num2.png');
                    app.num2PathEF.Value = filePath;
                    app.num2PathEF.BackgroundColor = 'white';

                    % Guardar o desenho do grafico numa imagem .png
                    exportgraphics(app.UIAxes,filePath,'Resolution',300);

                    % Adicionar um padding em volta da imagem
                    originalImage = imread(filePath);
                    paddedImage = padarray(originalImage,[120 320],255);

                    % Redimensionar a imagem para 150x150
                    paddedImage = imresize(paddedImage, app.IMG_RES);

                    % Reescrever a imagem
                    imwrite(rgb2gray(paddedImage),filePath);
                case 'Operador'
                    filePath = fullfile('..','images','op.png');
                    app.opPathEF.Value = filePath;
                    app.opPathEF.BackgroundColor = 'white';

                    % Guardar o desenho do grafico numa imagem .png
                    exportgraphics(app.UIAxes,filePath);

                    % Adicionar um padding em volta da imagem
                    originalImage = imread(filePath);
                    paddedImage = padarray(originalImage,[250 50],255);

                    % Redimensionar a imagem para 150x150
                    paddedImage = imresize(paddedImage, app.IMG_RES);

                    % Reescrever a imagem
                    imwrite(rgb2gray(paddedImage),filePath);
            end

            %imshow(app.grayImage, 'Parent', app.UIAxes);

            % No fim limpar a janela para desenhar denovo
            cla(app.UIAxes);
            hold(app.UIAxes, 'off');
            title(app.UIAxes, "Clique Esquerdo e Arrastar para desenhar");
        end

        % Button pushed function: simularDesenhoBtn
        function simularDesenhoBtnButtonPushed(app, event)
            if isempty(app.num1PathEF.Value)
                disp('Deve desenhar o primeiro numero.');
                app.num1PathEF.BackgroundColor = 'red';
            elseif isempty(app.num2PathEF.Value)
                disp('Deve desenhar o segundo numero.');
                app.num2PathEF.BackgroundColor = 'red';
            elseif isempty(app.opPathEF.Value)
                disp('Deve desenhar o operador.');
                app.opPathEF.BackgroundColor = 'red';
            elseif isempty(app.desenhoRedePathEF.Value)
                disp('[ERRO] Deve selecionar a rede previamente treinada');
                app.desenhoRedePathEF.BackgroundColor = 'red';
            else
                app.num1PathEF.BackgroundColor = 'white';
                app.num2PathEF.BackgroundColor = 'white';
                app.opPathEF.BackgroundColor = 'white';
                app.desenhoRedePathEF.BackgroundColor = 'white';

                % Resultado obtidos do treino
                num1 = trainFunction_d(app.num1PathEF.Value, app.desenhoRedePathEF.Value);
                num2 = trainFunction_d(app.num2PathEF.Value, app.desenhoRedePathEF.Value);
                op = trainFunction_d(app.opPathEF.Value, app.desenhoRedePathEF.Value);

                str = strcat(num1,op,num2,'=');
                switch(op)
                    case '+'
                        app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(num1) + str2num(num2)));
                    case '-'
                        app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(num1) - str2num(num2)));
                    case '*'
                        app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(num1) * str2num(num2)));
                    case '/'
                        app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(num1) / str2num(num2)));
                    otherwise
                        app.calculoDesenhoLbl.Text = strcat(str,'Erro a reconhecer...');
                end
            end
        end

        % Button pushed function: desenhoPesqRedeBtn
        function desenhoPesqRedeBtnButtonPushed(app, event)
            % Callback para selecionar a rede previamente treinada
            [file,path] = uigetfile(fullfile('..','networks','*.mat'));
            if isequal(file,0)
                disp('[Rede] O utilizador selecionou Cancelar');
            else
                disp(['[Rede] O utilizador selecionou "', fullfile(path,file), '"']);
                app.desenhoRedePathEF.Value = fullfile(path,file);
                app.desenhoRedePathEF.BackgroundColor = 'white';
            end
        end

        % Button pushed function: SimularOperadorButton
        function SimularOperadorButtonPushed(app, event)
            [file,path] = uigetfile(fullfile('..','networks','netOperators_*.mat'));
            if isequal(file,0)
                disp('[Rede] O utilizador selecionou Cancelar');
            else
                disp(['[Rede] O utilizador selecionou "', fullfile(path,file), '"']);
                app.desenhoRedePathEF.Value = fullfile(path,file);
                app.desenhoRedePathEF.BackgroundColor = 'white';

                if isempty(app.opPathEF.Value)
                    disp('Deve desenhar o primeiro numero.');
                    app.opPathEF.BackgroundColor = 'red';
                else
                    % Guardar o path numa var global
                    app.redeOperadores = fullfile(path,file);

                    % Treinar o operador
                    op = trainFunction_operators(app.opPathEF.Value, fullfile(path,file));

                    % Guardar o op numa var global
                    app.OP = op;

                    if app.NUM1==0 && app.NUM2==0
                        app.calculoDesenhoLbl.Text = op;
                    else
                        str = strcat(app.NUM1,op,app.NUM2,'=');
                        switch(op)
                            case '+'
                                app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(app.NUM1) + str2num(app.NUM2)));
                            case '-'
                                app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(app.NUM1) - str2num(app.NUM2)));
                            case '*'
                                app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(app.NUM1) * str2num(app.NUM2)));
                            case '/'
                                app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(app.NUM1) / str2num(app.NUM2)));
                            otherwise
                                app.calculoDesenhoLbl.Text = strcat(str,'Erro a reconhecer...');
                        end
                    end
                end
            end
        end

        % Button pushed function: SimularDigitosButton
        function SimularDigitosButtonPushed(app, event)
            [file,path] = uigetfile(fullfile('..','networks','netDigits_*.mat'));
            if isequal(file,0)
                disp('[Rede] O utilizador selecionou Cancelar');
            else
                disp(['[Rede] O utilizador selecionou "', fullfile(path,file), '"']);
                app.desenhoRedePathEF.Value = fullfile(path,file);
                app.desenhoRedePathEF.BackgroundColor = 'white';

                if isempty(app.num1PathEF.Value)
                    disp('Deve desenhar o primeiro numero.');
                    app.num1PathEF.BackgroundColor = 'red';
                elseif isempty(app.num2PathEF.Value)
                    disp('Deve desenhar o segundo numero.');
                    app.num2PathEF.BackgroundColor = 'red';
                else
                    % Guardar o path numa var global
                    app.redeDigitos = fullfile(path,file);

                    % Treinar os digitos
                    num1 = trainFunction_digits(app.num1PathEF.Value, fullfile(path,file));
                    num2 = trainFunction_digits(app.num2PathEF.Value, fullfile(path,file));

                    % Guardar os digitos em var globais
                    app.NUM1 = num1;
                    app.NUM2 = num2;

                    % Se o operador ainda nao tiver sido treinado, apenas
                    %   escreve os numeros na label
                    if isequal(app.OP,0)
                        app.calculoDesenhoLbl.Text = strcat(num2str(num1),' missing operator ',' ',num2str(num2));
                    % Caso contrario vai buscar os numeros as variaveis 
                    %   globais e efetua o calculo
                    else
                        str = strcat(num1,app.OP,num2,'=');
                        switch(app.OP)
                            case '+'
                                app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(num1) + str2num(num2)));
                            case '-'
                                app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(num1) - str2num(num2)));
                            case '*'
                                app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(num1) * str2num(num2)));
                            case '/'
                                app.calculoDesenhoLbl.Text = strcat(str,num2str(str2num(num1) / str2num(num2)));
                            otherwise
                                app.calculoDesenhoLbl.Text = strcat(str,'Erro a reconhecer...');
                        end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CriaodaRedeNeuronalPanel = uipanel(app.UIFigure);
            app.CriaodaRedeNeuronalPanel.Title = 'Criação da Rede Neuronal';
            app.ConfiguraodaRedePanel = uipanel(app.CriaodaRedeNeuronalPanel);
            app.ConfiguraodaRedePanel.Title = 'Configuração da Rede';
            app.FunodeTreinoDropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeTreinoDropDownLabel.Text = 'Função de Treino';
            app.funcTreinoDD = uidropdown(app.ConfiguraodaRedePanel);
            app.funcTreinoDD.Items = {'trainlm', 'trainbfg', 'trainrp', 'trainscg', 'traincgb', 'traincgf', 'traincgp', 'trainoss', 'traingdx'};
            app.funcTreinoDD.Value = 'trainlm';
            app.FunodeAtivao1DropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeAtivao1DropDownLabel.Text = 'Função de Ativação 1';
            app.funcAtivacao1DD = uidropdown(app.ConfiguraodaRedePanel);
            app.funcAtivacao1DD.Items = {'tansig', 'logsig', 'purelin'};
            app.funcAtivacao1DD.Value = 'tansig';
            app.FunodeAtivao2DropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeAtivao2DropDownLabel.Text = 'Função de Ativação 2';
            app.funcAtivacao2DD = uidropdown(app.ConfiguraodaRedePanel);
            app.funcAtivacao2DD.Items = {'tansig', 'logsig', 'purelin'};
            app.funcAtivacao2DD.Value = 'tansig';
            app.FunodeDivisoDropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeDivisoDropDownLabel.Text = 'Função de Divisão';
            app.funcDivisaoDD = uidropdown(app.ConfiguraodaRedePanel);
            app.funcDivisaoDD.Items = {'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.funcDivisaoDD.Value = 'dividerand';
            app.trainEditFieldLabel = uilabel(app.ConfiguraodaRedePanel);
            app.trainEditFieldLabel.Text = 'train';
            app.trainEF = uieditfield(app.ConfiguraodaRedePanel, 'numeric');
            app.trainEF.Value = 0.7;
            app.valEditFieldLabel = uilabel(app.ConfiguraodaRedePanel);
            app.valEditFieldLabel.Text = 'val';
            app.valEF = uieditfield(app.ConfiguraodaRedePanel, 'numeric');
            app.valEF.Value = 0.15;
            app.testEditFieldLabel = uilabel(app.ConfiguraodaRedePanel);
            app.testEditFieldLabel.Text = 'test';
            app.testEF = uieditfield(app.ConfiguraodaRedePanel, 'numeric');
            app.testEF.Value = 0.15;
            app.NmerodeneurniosLabel = uilabel(app.ConfiguraodaRedePanel);
            app.NmerodeneurniosLabel.Text = 'Número de neurónios';
            app.Camada1SpinnerLabel = uilabel(app.ConfiguraodaRedePanel);
            app.Camada1SpinnerLabel.Text = 'Camada 1';
            app.camada1Spinner = uispinner(app.ConfiguraodaRedePanel);
            app.camada1Spinner.Limits = [1 10];
            app.camada1Spinner.Value = 5;
            app.Camada2SpinnerLabel = uilabel(app.ConfiguraodaRedePanel);
            app.Camada2SpinnerLabel.Text = 'Camada 2';
            app.camada2Spinner = uispinner(app.ConfiguraodaRedePanel);
            app.camada2Spinner.Limits = [1 10];
            app.camada2Spinner.Editable = 'off';
            app.camada2Spinner.Enable = 'off';
            app.camada2Spinner.Value = 5;
            app.FunodeAtivao3Label = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeAtivao3Label.Text = 'Função de Ativação 3';
            app.funcAtivacao3DD = uidropdown(app.ConfiguraodaRedePanel);
            app.funcAtivacao3DD.Items = {'tansig', 'logsig', 'purelin'};
            app.funcAtivacao3DD.Enable = 'off';
            app.funcAtivacao3DD.Value = 'tansig';
            app.Camada3SpinnerLabel = uilabel(app.ConfiguraodaRedePanel);
            app.Camada3SpinnerLabel.Text = 'Camada 3';
            app.camada3Spinner = uispinner(app.ConfiguraodaRedePanel);
            app.camada3Spinner.Limits = [1 10];
            app.camada3Spinner.Enable = 'off';
            app.camada3Spinner.Value = 5;
            app.CamadasEscondidasSpinnerLabel = uilabel(app.ConfiguraodaRedePanel);
            app.CamadasEscondidasSpinnerLabel.Text = 'Camadas Escondidas';
            app.camadasEscondidasSpinner = uispinner(app.ConfiguraodaRedePanel);
            app.camadasEscondidasSpinner.Limits = [1 3];
            app.camadasEscondidasSpinner.ValueChangedFcn = createCallbackFcn(app, @camadasEscondidasSpinnerValueChanged, true);
            app.camadasEscondidasSpinner.Value = 1;
            app.InputsPanel = uipanel(app.CriaodaRedeNeuronalPanel);
            app.InputsPanel.Title = 'Inputs';
            app.PastaparaTreinoDropDownLabel = uilabel(app.InputsPanel);
            app.PastaparaTreinoDropDownLabel.Text = 'Pasta para Treino';
            app.pastaDD = uidropdown(app.InputsPanel);
            app.pastaDD.Items = {'start', 'train', 'custom'};
            app.pastaDD.Value = 'start';
            app.NomedaRedeEditFieldLabel = uilabel(app.InputsPanel);
            app.NomedaRedeEditFieldLabel.Text = 'Nome da Rede';
            app.nomeRedeEF = uieditfield(app.InputsPanel, 'text');
            app.CriareTreinarRedeButton = uibutton(app.CriaodaRedeNeuronalPanel, 'push');
            app.CriareTreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CriareTreinarRedeButtonPushed, true);
            app.CriareTreinarRedeButton.Text = 'Criar e Treinar Rede';
            app.ResultadoPanel = uipanel(app.CriaodaRedeNeuronalPanel);
            app.ResultadoPanel.Title = 'Resultado';
            app.lblResultadoTreino = uilabel(app.ResultadoPanel);
            app.lblResultadoTreino.Text = '';
            app.SimulaodeClculosPanel = uipanel(app.UIFigure);
            app.SimulaodeClculosPanel.Title = 'Simulação de Cálculos';
            app.InserirImagensManualmentePanel = uipanel(app.SimulaodeClculosPanel);
            app.InserirImagensManualmentePanel.Title = 'Inserir Imagens Manualmente';
            app.num1Btn = uibutton(app.InserirImagensManualmentePanel, 'push');
            app.num1Btn.ButtonPushedFcn = createCallbackFcn(app, @num1BtnButtonPushed, true);
            app.num1Btn.Text = 'Pesquisar';
            app.Numero1EditFieldLabel = uilabel(app.InserirImagensManualmentePanel);
            app.Numero1EditFieldLabel.Text = 'Numero 1';
            app.num1EF = uieditfield(app.InserirImagensManualmentePanel, 'text');
            app.pathLabel = uilabel(app.InserirImagensManualmentePanel);
            app.pathLabel.Text = 'Caminho para o ficheiro';
            app.Numero2Label = uilabel(app.InserirImagensManualmentePanel);
            app.Numero2Label.Text = 'Numero 2';
            app.num2EF = uieditfield(app.InserirImagensManualmentePanel, 'text');
            app.OperadorEditFieldLabel = uilabel(app.InserirImagensManualmentePanel);
            app.OperadorEditFieldLabel.Text = 'Operador';
            app.opEF = uieditfield(app.InserirImagensManualmentePanel, 'text');
            app.pathLabel_2 = uilabel(app.InserirImagensManualmentePanel);
            app.pathLabel_2.Text = 'Rede Neuronal';
            app.redeBtn = uibutton(app.InserirImagensManualmentePanel, 'push');
            app.redeBtn.ButtonPushedFcn = createCallbackFcn(app, @redeBtnButtonPushed, true);
            app.redeBtn.Text = 'Pesquisar';
            app.CalcularButton = uibutton(app.InserirImagensManualmentePanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.limparBtn = uibutton(app.InserirImagensManualmentePanel, 'push');
            app.limparBtn.ButtonPushedFcn = createCallbackFcn(app, @limparBtnButtonPushed, true);
            app.limparBtn.Text = 'Limpar';
            app.CaminhoparaaredeEditFieldLabel = uilabel(app.InserirImagensManualmentePanel);
            app.CaminhoparaaredeEditFieldLabel.Text = 'Caminho para a rede';
            app.redeEF = uieditfield(app.InserirImagensManualmentePanel, 'text');
            app.panelResultadoCalculo = uipanel(app.InserirImagensManualmentePanel);
            app.panelResultadoCalculo.Title = 'Resultado';
            app.calculoLbl = uilabel(app.panelResultadoCalculo);
            app.calculoLbl.Text = '';
            app.num2Btn = uibutton(app.InserirImagensManualmentePanel, 'push');
            app.num2Btn.ButtonPushedFcn = createCallbackFcn(app, @num2BtnButtonPushed, true);
            app.num2Btn.Text = 'Pesquisar';
            app.opBtn = uibutton(app.SimulaodeClculosPanel, 'push');
            app.opBtn.ButtonPushedFcn = createCallbackFcn(app, @opBtnButtonPushed, true);
            app.opBtn.Text = 'Pesquisar';
            app.DesenharPanel = uipanel(app.SimulaodeClculosPanel);
            app.DesenharPanel.Title = 'Desenhar';
            app.UIAxes = uiaxes(app.DesenharPanel);
            title(app.UIAxes, 'Clique Esquerdo e Arrastar para desenhar')
            app.UIAxes.XLim = [0 1000];
            app.UIAxes.YLim = [0 1000];
            app.UIAxes.ZLim = [0 1000];
            app.UIAxes.XTick = [0 100 200 300 400 500 600 700 800 900 1000];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 100 200 300 400 500 600 700 800 900 1000];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.panelResultadoDesenho = uipanel(app.DesenharPanel);
            app.panelResultadoDesenho.Title = 'Resultado';
            app.calculoDesenhoLbl = uilabel(app.panelResultadoDesenho);
            app.calculoDesenhoLbl.Text = '';
            app.simularDesenhoBtn = uibutton(app.DesenharPanel, 'push');
            app.simularDesenhoBtn.ButtonPushedFcn = createCallbackFcn(app, @simularDesenhoBtnButtonPushed, true);
            app.simularDesenhoBtn.Text = 'Simular Todos';
            app.opDesenhoRadioGroup = uibuttongroup(app.DesenharPanel);
            app.opDesenhoRadioGroup.Title = 'Opção de Desenho';
            app.num1Radio = uiradiobutton(app.opDesenhoRadioGroup);
            app.num1Radio.Text = 'Numero 1';
            app.num1Radio.Value = true;
            app.num2Radio = uiradiobutton(app.opDesenhoRadioGroup);
            app.num2Radio.Text = 'Numero 2';
            app.opRadio = uiradiobutton(app.opDesenhoRadioGroup);
            app.opRadio.Text = 'Operador';
            app.num1PathEF = uieditfield(app.opDesenhoRadioGroup, 'text');
            app.num1PathEF.Editable = 'off';
            app.num2PathEF = uieditfield(app.opDesenhoRadioGroup, 'text');
            app.num2PathEF.Editable = 'off';
            app.opPathEF = uieditfield(app.opDesenhoRadioGroup, 'text');
            app.opPathEF.Editable = 'off';
            app.RedeLabel = uilabel(app.DesenharPanel);
            app.RedeLabel.Text = 'Rede';
            app.desenhoRedePathEF = uieditfield(app.DesenharPanel, 'text');
            app.desenhoRedePathEF.Editable = 'off';
            app.desenhoPesqRedeBtn = uibutton(app.DesenharPanel, 'push');
            app.desenhoPesqRedeBtn.ButtonPushedFcn = createCallbackFcn(app, @desenhoPesqRedeBtnButtonPushed, true);
            app.desenhoPesqRedeBtn.Text = 'Pesquisar';
            app.SimularOperadorButton = uibutton(app.DesenharPanel, 'push');
            app.SimularOperadorButton.ButtonPushedFcn = createCallbackFcn(app, @SimularOperadorButtonPushed, true);
            app.SimularOperadorButton.Text = 'Simular Operador';
            app.SimularDigitosButton = uibutton(app.DesenharPanel, 'push');
            app.SimularDigitosButton.ButtonPushedFcn = createCallbackFcn(app, @SimularDigitosButtonPushed, true);
            app.SimularDigitosButton.Text = 'Simular Digitos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end